classdef Ford_Fulkerson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TP_MatlabUIFigure            matlab.ui.Figure
        TRAVAUXPRATIQUEMATLABAlgorithmedeFordFulkersonLabel  matlab.ui.control.Label
        AjouterArcButton             matlab.ui.control.Button
        SourceEditFieldLabel         matlab.ui.control.Label
        SourceEditField              matlab.ui.control.EditField
        DestinationEditFieldLabel    matlab.ui.control.Label
        DestinationEditField         matlab.ui.control.EditField
        PoidsEditFieldLabel          matlab.ui.control.Label
        PoidsEditField               matlab.ui.control.NumericEditField
        SourceEditField_2Label       matlab.ui.control.Label
        SourceEditField_2            matlab.ui.control.EditField
        DestinationEditField_2Label  matlab.ui.control.Label
        DestinationEditField_2       matlab.ui.control.EditField
        LancerlAlgorithmeButton      matlab.ui.control.Button
        RecherchedufluxmaximalentrelasourceetladestinationLabel  matlab.ui.control.Label
        RinitialiserlegrapheButton   matlab.ui.control.StateButton
        SupprimerArcButton           matlab.ui.control.StateButton
        RalisparKABOREYabyourEricING1IAI2017Label  matlab.ui.control.Label
        TRAVAUXPRATIQUEMATLABAlgorithmedeFordFulkersonLabel_2  matlab.ui.control.Label
    end


    properties (Access = private)
         G=digraph(); % Description
        
    end


    methods (Access = private)

        % Button pushed function: AjouterArcButton
        function AjouterArcButtonPushed(app, event)
            
           app.G=ajouter_arc(app.G,app.SourceEditField.Value,app.DestinationEditField.Value,app.PoidsEditField.Value);
           app.G,app.SourceEditField.Value='';
           app.DestinationEditField.Value='';
           app.PoidsEditField.Value=0;
           app.TP_MatlabUIFigure.Position;
           app.TP_MatlabUIFigure.Position=[50 100 640 480];
           subplot(2,2,1),plot(app.G,'EdgeLabel',app.G.Edges.Weight),title('Graphe initial');
            
            
        end

        % Value changed function: SourceEditField
        function SourceEditFieldValueChanged(app, event)
            value = app.SourceEditField.Value; 
            
        end

        % Value changed function: DestinationEditField
        function DestinationEditFieldValueChanged(app, event)
            value = app.DestinationEditField.Value;
            
        end

        % Value changed function: PoidsEditField
        function PoidsEditFieldValueChanged(app, event)
            value = app.PoidsEditField.Value;
            
        end

        % Value changed function: RinitialiserlegrapheButton
        function RinitialiserlegrapheButtonValueChanged(app, event)
            value = app.RinitialiserlegrapheButton.Value;
            app.G,app.SourceEditField.Value='';
            app.DestinationEditField.Value='';
            app.PoidsEditField.Value=0;
            app.G=digraph();
            app.TP_MatlabUIFigure.Position;
            app.TP_MatlabUIFigure.Position=[50 100 640 480];
            subplot(2,2,1),plot(0),title('Graphe initial');
            
            
        end

        % Value changed function: SupprimerArcButton
        function SupprimerArcButtonValueChanged(app, event)
           app.G=rmedge(app.G,app.SourceEditField.Value,app.DestinationEditField.Value);
           app.G,app.SourceEditField.Value='';
           app.DestinationEditField.Value='';
           app.PoidsEditField.Value=0;
           app.TP_MatlabUIFigure.Position;
           app.TP_MatlabUIFigure.Position=[50 100 640 480];
           subplot(2,2,1),plot(app.G,'EdgeLabel',app.G.Edges.Weight),title('Graphe initial');
            
        end

        % Button pushed function: LancerlAlgorithmeButton
        function LancerlAlgorithmeButtonPushed(app, event)
            source=app.SourceEditField_2.Value;
            destination=app.DestinationEditField_2.Value;
            fi=Fulkerson(app.G,source,destination);
        end

        % Value changed function: SourceEditField_2
        function SourceEditField_2ValueChanged(app, event)
            value = app.SourceEditField_2.Value;
            
        end

        % Value changed function: DestinationEditField_2
        function DestinationEditField_2ValueChanged(app, event)
            value = app.DestinationEditField_2.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TP_MatlabUIFigure = uifigure;
            app.TP_MatlabUIFigure.Name = 'TP_Matlab';
            setAutoResize(app, app.TP_MatlabUIFigure, true)
            app.TRAVAUXPRATIQUEMATLABAlgorithmedeFordFulkersonLabel = uilabel(app.TP_MatlabUIFigure);
            app.TRAVAUXPRATIQUEMATLABAlgorithmedeFordFulkersonLabel.Text = 'TRAVAUX PRATIQUES Théorie des Graphes & Recherche Opérationnelle';
            app.AjouterArcButton = uibutton(app.TP_MatlabUIFigure, 'push');
            app.AjouterArcButton.ButtonPushedFcn = createCallbackFcn(app, @AjouterArcButtonPushed, true);
            app.AjouterArcButton.Text = 'Ajouter Arc';
            app.SourceEditFieldLabel = uilabel(app.TP_MatlabUIFigure);
            app.SourceEditFieldLabel.Text = 'Source';
            app.SourceEditField = uieditfield(app.TP_MatlabUIFigure, 'text');
            app.SourceEditField.ValueChangedFcn = createCallbackFcn(app, @SourceEditFieldValueChanged, true);
            app.DestinationEditFieldLabel = uilabel(app.TP_MatlabUIFigure);
            app.DestinationEditFieldLabel.Text = 'Destination';
            app.DestinationEditField = uieditfield(app.TP_MatlabUIFigure, 'text');
            app.DestinationEditField.ValueChangedFcn = createCallbackFcn(app, @DestinationEditFieldValueChanged, true);
            app.PoidsEditFieldLabel = uilabel(app.TP_MatlabUIFigure);
            app.PoidsEditFieldLabel.Text = 'Poids';
            app.PoidsEditField = uieditfield(app.TP_MatlabUIFigure, 'numeric');
            app.PoidsEditField.ValueChangedFcn = createCallbackFcn(app, @PoidsEditFieldValueChanged, true);
            app.SourceEditField_2Label = uilabel(app.TP_MatlabUIFigure);
            app.SourceEditField_2Label.Text = 'Source';
            app.SourceEditField_2 = uieditfield(app.TP_MatlabUIFigure, 'text');
            app.SourceEditField_2.ValueChangedFcn = createCallbackFcn(app, @SourceEditField_2ValueChanged, true);
            app.DestinationEditField_2Label = uilabel(app.TP_MatlabUIFigure);
            app.DestinationEditField_2Label.Text = 'Destination';
            app.DestinationEditField_2 = uieditfield(app.TP_MatlabUIFigure, 'text');
            app.DestinationEditField_2.ValueChangedFcn = createCallbackFcn(app, @DestinationEditField_2ValueChanged, true);
            app.LancerlAlgorithmeButton = uibutton(app.TP_MatlabUIFigure, 'push');
            app.LancerlAlgorithmeButton.ButtonPushedFcn = createCallbackFcn(app, @LancerlAlgorithmeButtonPushed, true);
            app.LancerlAlgorithmeButton.Text = 'Lancer l''Algorithme';
            app.RecherchedufluxmaximalentrelasourceetladestinationLabel = uilabel(app.TP_MatlabUIFigure);
            app.RecherchedufluxmaximalentrelasourceetladestinationLabel.Text = 'Recherche du flux maximal entre la source et la destination';
            app.RinitialiserlegrapheButton = uibutton(app.TP_MatlabUIFigure, 'state');
            app.RinitialiserlegrapheButton.ValueChangedFcn = createCallbackFcn(app, @RinitialiserlegrapheButtonValueChanged, true);
            app.RinitialiserlegrapheButton.Text = 'Réinitialiser le graphe';
            app.SupprimerArcButton = uibutton(app.TP_MatlabUIFigure, 'state');
            app.SupprimerArcButton.ValueChangedFcn = createCallbackFcn(app, @SupprimerArcButtonValueChanged, true);
            app.SupprimerArcButton.Text = 'Supprimer Arc';
            app.RalisparKABOREYabyourEricING1IAI2017Label = uilabel(app.TP_MatlabUIFigure);
            app.RalisparKABOREYabyourEricING1IAI2017Label.Text = 'Réalisé par: KABORE Yabyouré Eric, ING1@IAI 2017';
            app.TRAVAUXPRATIQUEMATLABAlgorithmedeFordFulkersonLabel_2 = uilabel(app.TP_MatlabUIFigure);
            app.TRAVAUXPRATIQUEMATLABAlgorithmedeFordFulkersonLabel_2.Text = 'Implémentation de l''algorithme de Ford & Fulkerson';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Ford_Fulkerson()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TP_MatlabUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TP_MatlabUIFigure)
        end
    end
end